classdef SampSampGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        fileToolbar     matlab.ui.container.Menu
        saveFcn         matlab.ui.container.Menu
        loadFcn         matlab.ui.container.Menu
        exitFcn         matlab.ui.container.Menu
        openFile        matlab.ui.control.Button
        listbox         matlab.ui.control.ListBox
        TriggerPanel    matlab.ui.container.Panel
        stopTrigger     matlab.ui.container.ButtonGroup
        MintrigButton   matlab.ui.control.RadioButton
        MaxtrigButton   matlab.ui.control.RadioButton
        trigOrNot       matlab.ui.control.DropDown
        triggerUnit_2   matlab.ui.control.Label
        triggerUnit_1   matlab.ui.control.Label
        startTrigValue  matlab.ui.control.EditField
        startTrigger    matlab.ui.control.Label
        stopTrigValue   matlab.ui.control.EditField
        AnalogInput     matlab.ui.container.ButtonGroup
        a0              matlab.ui.control.CheckBox
        a7              matlab.ui.control.CheckBox
        a6              matlab.ui.control.CheckBox
        a5              matlab.ui.control.CheckBox
        a4              matlab.ui.control.CheckBox
        a3              matlab.ui.control.CheckBox
        a2              matlab.ui.control.CheckBox
        a1              matlab.ui.control.CheckBox
        Save            matlab.ui.container.Panel
        name            matlab.ui.control.EditField
        openFolder      matlab.ui.control.Button
        saveDir         matlab.ui.control.Label
        startPanel      matlab.ui.container.Panel
        Device          matlab.ui.control.DropDown
        text12          matlab.ui.control.Label
        text11          matlab.ui.control.Label
        text10          matlab.ui.control.Label
        delSam          matlab.ui.control.EditField
        text9           matlab.ui.control.Label
        stopDAQ         matlab.ui.control.Button
        sec             matlab.ui.control.EditField
        TimeoutText     matlab.ui.control.Label
        freqText        matlab.ui.control.Label
        freq            matlab.ui.control.EditField
        startDAQ        matlab.ui.control.Button
        triggerPlot     matlab.ui.control.UIAxes
        dataPlot        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        daqConnection = "Dev1"; % Refers to what NI device is being used
        daqName = "ni"; % Name of daqDevice
        daqDevice % Handle to connected DAQ hardware object
        analogueInputChoice = [1, 0, 0, 0, 0, 0, 0, 1]; % What is the chosen input source (represents a0 - a7)
        sampFrequency % What frequency is being recorded at
        timeout % How long, in seconds, will it take to timeout the recording
        sampleDelay % Delay how many samples before recording data
        useTrigger
        startTrigVal
        stopTrigVal
        aboveUnder = true % Change to soething else, confusing

        % Bounderies for the plotwindows
        axisXmin = 0; 
        axisXmax = 11;
        axisYmin = 0;
        axisYmax = 3;  

        delaySamples= 0;     % How many samples to store from before the start trigger
        countOnOff = 2;      % Counter that makes sure that start button is green when sampling end red when not(that it dosent flicker)        
        subPlot = '1:5:end'; % Plots every 5th sample

        % Define properties regarding saving data
        fileNameData = 'data_block';       % Each data block gets a name data_block1,...,data_block20,....
        fileNameTime = 'ticktimes_block';  % To each block of data a corresponding time stamp is saved with the name ticktimes_block1,....tcktimes_block20,....
        dataBlock = 'data_block';          % Each data block gets a name data_block1,...,data_block20,....
        fileCounter = 1;                   % Name counter of data files ex. data_block1, data_block2        
    end
    
    methods (Access = private)
        function basicDaqRealTimePlotCallback(~, hObject, ~, ~, samHandles)
            if hObject.samplesAvailable>0
            
                %this callback for no trigger
                size = hObject.SamplesAvailable;
                data = peekdata(hObject,size);
                cla(samHandles.plotFigDataAxis);
                plot(samHandles.plotFigDataAxis,data(:,1))
                cla (samHandles.plotFigTrig);
                plot(samHandles.plotFigTrig,data(1:10:end,2));
            
            end
        end
        
        function daqRealTimePlotCallback(~, hObject, ~, ~, samHandles)
            % hObject    handle to daqrealtimeplot (see GCBO)
            size = hObject.SamplesAvailable;
            if hObject.SamplesAvailable > 160
            
            
                lastSamps=peekdata(hObject, samHandles.stopSize);
                data=peekdata(hObject,size);
            
                if samHandles.aboveUnder
                    test = max(lastSamps);
                    test = test(1);
                else
                    test = min(lastSamps);
                    test = test(1);
                end
                if test<samHandles.stopTrig
                    stop(hObject)
                    counter = getappdata(0,'counter');
                    samps = hObject.SamplesAvailable;
                    [data,~,~]=getdata(hObject,samps);
            
                    samHandles.fileNameData = [samHandles.dataBlock num2str(counter)];
                    %%%value to ticktimes
                    
                    fileNameData = [samHandles.dataBlock num2str(counter)];
                    fileNameTime = [samHandles.fileNameTime num2str(counter)];
            
                    eval([sprintf(fileNameData) '=data'';']);
                    eval([sprintf(fileNameTime) '=ticktimes_block;']);
            
                    save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat']...
                        ,fileNameData,fileNameTime,'-append');
            
                    %---Save the handle structure-----
                    counter = counter + 1;
                    setappdata(0,'counter',counter);
                    setappdata(0,'samHandles',samHandles);
                    %---------------------------------
            
                    %---start the daq-object again-----
                    try
                        start(hObject)
                    catch
                        disp 'Timed out';
                    end
                    %----------------------------------
                end
            
                N=length(data(:,1));
                T=N/samHandles.freq;
                x=linspace(0,T,N);
            
                if T<11
                    cla(samHandles.plotFigTrigAxis)
                    axis(samHandles.plotFigTrigAxis,[0 11 0 4])
                    hold (samHandles.plotFigTrigAxis,'on')
            
                    plot(samHandles.plotFigTrigAxis,x,data(sprintf(samHandles.subPlot),1));
                    hold (samHandles.plotFigTrigAxis,'off')
                else
                    x2=linspace(T-10,T,10*samHandles.freq+1);
                    plot(samHandles.plotFigTrigAxis,x2,data(end-samHandles.freq*10:end,1));
                end
            
                if T<11
                    cla(samHandles.plotFigDataAxis);
                    axis(samHandles.plotFigDataAxis,[0 11 0 0.2])
                    hold (samHandles.plotFigDataAxis,'on')
                    axis (samHandles.plotFigDataAxis,'auto y');
                    plot(samHandles.plotFigDataAxis,x,data(sprintf(samHandles.subPlot),2));
                    hold (samHandles.plotFigDataAxis,'off')
                else
                    plot(samHandles.plotFigDataAxis,x2,data(end-samHandles.freq*10:end,2));
                end
            end
        end
        
        function timerCallback(~, ~, ~, handles)
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get default command line output from handles structure
            if ~isempty(daqfind)
                ai = daqfind;
                samHandles = getappdata(0,'samHandles');
                if strcmp(ai.Running, 'On') && samHandles.countOnOff>1
                    disp start
                    set(handles.startDAQ,'ForegroundColor', [0 1 0]);
                    samHandles.countOnOff = 0;
                end
                if strcmp(ai.Running, 'Off')&& samHandles.countOnOff<2
                    samHandles.countOnOff = samHandles.countOnOff + 1;
                    if samHandles.countOnOff>1
                        samHandles.fileNameData = [samHandles.dataBlock];
                
                        fileNameData = [samHandles.dataBlock];
                        fileNameTime = [samHandles.fileNameTime];
                
                        eval([sprintf(fileNameData) '=data'';']);
                        eval([sprintf(fileNameTime) '=ticktimes_block;']);
                
                        save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat']...
                            ,fileNameData,fileNameTime,'-append');
                
                        disp stop
                        set(handles.startDAQ,'ForegroundColor',[1 0 0]);
                    end
                end
            
            setappdata(0,'samHandles',samHandles);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sampsamp_OpeningFcn(app, varargin)
            % --- Executes just before sampsamp is made visible.

            uiWindow=gcf;

            % Set access to figure window for easy editing
            setappdata(0,'samsam',uiWindow);
            
            % Delete any existing timer objects to prevent errors
            if ~isempty(timerfind)
                stop(timerfind)
                delete(timerfind)
            end
            
        end

        % Menu selected function: exitFcn
        function exit_Callback(app, event)
            % --- Executes on button press in exit.
            close(gcf)
        end

        % Value changed function: listbox
        function listbox_Callback(app, event)
            % --- Executes on selection change in listbox.
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox

            val = app.listbox.Value;

            if val > 1
                load(app.matFile,app.files(val-1).name)
            
                plot(app.allDataPlot,eval([app.files(val-1).name '(1,:)']))
            end

        end

        % Button pushed function: openFile
        function openFile_Callback(app, event)
            % --- Executes on button press in openFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [file, folder]= uigetfile('');
            if isequal(file,0) || isequal(folder,0)
                disp('Operation canceled')
            else
            
                handles.matFile = [folder file];
                handles.files = whos ('-file', [folder file]);
                storlek = size(handles.files);
                N = (storlek(1)-1)/2;
            
                set(handles.listbox,'String',{'load new file'});
                for i = 1:N-3
                    oldString = get(handles.listbox,'String');
                    set(handles.listbox,'String', [oldString(:)' {handles.files(i).name}])
                end
            end
            guidata(hObject,handles)
        end

        % Button pushed function: openFolder
        function openFolder_Callback(app, event)
            % --- Executes on button press in openFolder.
            
            dir=uigetdir;
            if ~dir==0 % If not cancelled, save user choice
                app.saveDir.Text = dir;
            end
        end

        % Button pushed function: stopDAQ
        function stopDAQ_Callback(app, event)
            % --- Executes on button press in reset.
            
            if ~isempty(app.daqDevice)
                stop(app.daqDevice);
            end
        end

        % Button pushed function: startDAQ
        function startDAQ_Callback(app, event)
            % --- Executes on button press in startDAQ.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to startDAQ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            daqreset;
            
            recordStart = datetime("now");
            newFolder = ([app.saveDir.Text '\' recordStart]);
            mkdir(newFolder);
            
            info = '---------Data from sampsamp--------------';
            save([newFolder '\' recordStart '.mat'],'info');
            
            % ------------------------------------------------------

            files={''};
            setappdata(0,'Files',files);

            % Creates the interface to the measuring device
            app.daqDevice = daq(app.daqName);

            % Function to load selected channels and apply daq settings to daq object 
            app.daqDevice = aiLoader(app);

            % Obtain sample frequency from DAQ object
            app.sampFrequency = get(app.daqConnection,'SampleRate');
            
            % Timer function for running collor
            
            if ~isempty(timerfind)
                stop(timerfind)
                delete(timerfind)
            end
            
            t = timer('TimerFcn', @(h, ev) timerCallback(app, h, ev, handles));
            t.executionMode = 'fixedRate';
            t.Period = 0.25;
            %---------------------------------------------------------
            if get(handles.trigOrNot, 'Value') == 1 
                set(app.daqConnection, 'TimerFcn',{@app.daqRealTimePlotCallback,handles,samHandles})%or some reason this doesent work when in aiLoader
            else
                set(app.daqConnection, 'TimerFcn', {@app.basicDaqRealTimePlotCallback,handles,samHandles})
            end
            
            counter = 1;
            setappdata(0,'counter',counter);

            try
                start(app.daqConnection);
                start(t);
                tic
            catch
                disp 'error';
            end
            
            if get(handles.trigOrNot,'Value') == 0
                [data,time,abstime]=getdata(app.daqConnection);
                save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat'], 'data','time','abstime', '-append')
            end
        end

        % Value changed function: trigOrNot
        function trigOrNot_Callback(app, event)
            % --- Executes on selection change in trigOrNot.

            val = app.trigOrNot.Value;
            app.useTrigger = val;
            switch val
                case "With trigger"
                    % Enable trigger options
                    app.startTrigger.Enable = 'On';
                    app.startTrigValue.Enable = 'On';
                    app.triggerUnit_1.Enable = 'On';
                    app.triggerUnit_2.Enable = 'On';
                    app.stopTrigger.Enable = 'On';
                    app.stopTrigValue.Enable = 'On';

                    % Allow user freqs and timeouts
                    app.freq.Enable = 'On';
                    app.freq.Value = num2str(app.sampFrequency);
                    app.sec.Enable = 'On';
                    app.sec.Value = num2str(app.timeout);
                case "No trigger"
                    % Disable trigger options
                    app.startTrigger.Enable = 'Off';
                    app.startTrigValue.Enable = 'Off';
                    app.triggerUnit_1.Enable = 'Off';
                    app.triggerUnit_2.Enable = 'Off';
                    app.stopTrigger.Enable = 'Off';
                    app.stopTrigValue.Enable = 'Off';

                    %Force default values
                    app.freq.Enable = 'Off';
                    app.freq.Value = '40000';
                    app.sec.Enable = 'Off';
                    app.sec.Value = '1800';
            end
        end

        % Menu selected function: saveFcn
        function saveFcnMenuSelected(app, event)
            
        end

        % Menu selected function: loadFcn
        function loadFcnMenuSelected(app, event)
            
        end

        % Value changed function: startTrigValue
        function startTrigValueValueChanged(app, event)
            value = app.startTrigValue.Value;
            app.startTrigVal = value;
        end

        % Value changed function: stopTrigValue
        function stopTrigValueValueChanged(app, event)
            value = app.stopTrigValue.Value;
            app.stopTrigVal = value;
        end

        % Value changed function: freq
        function freqValueChanged(app, event)
            value = app.freq.Value;
            app.sampFrequency = value;
        end

        % Value changed function: sec
        function secValueChanged(app, event)
            value = app.sec.Value;
            app.timeout = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'sampsamp';
            app.figure1.Tag = 'figure1';
            app.fileToolbar = uimenu(app.figure1);
            app.fileToolbar.Text = 'File';
            app.fileToolbar.Tag = 'uimenu1';
            app.saveFcn = uimenu(app.fileToolbar);
            app.saveFcn.MenuSelectedFcn = createCallbackFcn(app, @saveFcnMenuSelected, true);
            app.saveFcn.Text = 'Save Config';
            app.loadFcn = uimenu(app.fileToolbar);
            app.loadFcn.MenuSelectedFcn = createCallbackFcn(app, @loadFcnMenuSelected, true);
            app.loadFcn.Text = 'Load Config';
            app.exitFcn = uimenu(app.fileToolbar);
            app.exitFcn.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exitFcn.Text = 'Exit';
            app.dataPlot = uiaxes(app.figure1);
            app.dataPlot.Tag = 'dataPlot';
            app.triggerPlot = uiaxes(app.figure1);
            app.triggerPlot.Tag = 'triggerPlot';
            app.startPanel = uipanel(app.figure1);
            app.startPanel.Title = 'Sampling';
            app.startPanel.Tag = 'startPanel';
            app.startDAQ = uibutton(app.startPanel, 'push');
            app.startDAQ.ButtonPushedFcn = createCallbackFcn(app, @startDAQ_Callback, true);
            app.startDAQ.Tag = 'startDAQ';
            app.startDAQ.Text = 'Start-DAQ';
            app.freq = uieditfield(app.startPanel, 'text');
            app.freq.ValueChangedFcn = createCallbackFcn(app, @freqValueChanged, true);
            app.freq.Tag = 'freq';
            app.freq.Value = '40000';
            app.freqText = uilabel(app.startPanel);
            app.freqText.Tag = 'freqText';
            app.freqText.Text = 'f [Hz]';
            app.TimeoutText = uilabel(app.startPanel);
            app.TimeoutText.Tag = 'TimeoutText';
            app.TimeoutText.Text = 'Timeout';
            app.sec = uieditfield(app.startPanel, 'text');
            app.sec.ValueChangedFcn = createCallbackFcn(app, @secValueChanged, true);
            app.sec.Tag = 'sec';
            app.sec.Value = '1200';
            app.stopDAQ = uibutton(app.startPanel, 'push');
            app.stopDAQ.ButtonPushedFcn = createCallbackFcn(app, @stopDAQ_Callback, true);
            app.stopDAQ.Tag = 'reset';
            app.stopDAQ.Text = 'Stop-DAQ';
            app.text9 = uilabel(app.startPanel);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Delay';
            app.delSam = uieditfield(app.startPanel, 'text');
            app.delSam.Tag = 'delSam';
            app.delSam.Value = '25';
            app.text10 = uilabel(app.startPanel);
            app.text10.Tag = 'text10';
            app.text10.Text = '[Hz]';
            app.text11 = uilabel(app.startPanel);
            app.text11.Tag = 'text11';
            app.text11.Text = '[s]';
            app.text12 = uilabel(app.startPanel);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Samples';
            app.Device = uidropdown(app.startPanel);
            app.Device.Items = {'Dev1', 'Dev2', 'Dev3'};
            app.Device.Tag = 'Device';
            app.Device.Value = 'Dev2';
            app.Save = uipanel(app.figure1);
            app.Save.Title = 'Save Options';
            app.Save.Tag = 'Save';
            app.saveDir = uilabel(app.Save);
            app.saveDir.Tag = 'saveDir';
            app.saveDir.Text = 'E:\Yuri';
            app.openFolder = uibutton(app.Save, 'push');
            app.openFolder.ButtonPushedFcn = createCallbackFcn(app, @openFolder_Callback, true);
            app.openFolder.Tag = 'openFolder';
            app.openFolder.Text = 'Open folder';
            app.name = uieditfield(app.Save, 'text');
            app.name.Tag = 'name';
            app.name.Value = 'TagnameOfFile';
            app.AnalogInput = uibuttongroup(app.figure1);
            app.AnalogInput.Title = 'Analog input';
            app.AnalogInput.Tag = 'AnalogInput';
            app.a1 = uicheckbox(app.AnalogInput);
            app.a1.Tag = 'a1';
            app.a1.Text = 'a1';
            app.a2 = uicheckbox(app.AnalogInput);
            app.a2.Tag = 'a2';
            app.a2.Text = 'a2';
            app.a3 = uicheckbox(app.AnalogInput);
            app.a3.Tag = 'a3';
            app.a3.Text = 'a3';
            app.a4 = uicheckbox(app.AnalogInput);
            app.a4.Tag = 'a4';
            app.a4.Text = 'a4';
            app.a5 = uicheckbox(app.AnalogInput);
            app.a5.Tag = 'a5';
            app.a5.Text = 'a5';
            app.a6 = uicheckbox(app.AnalogInput);
            app.a6.Tag = 'a6';
            app.a6.Text = 'a6';
            app.a7 = uicheckbox(app.AnalogInput);
            app.a7.Tag = 'a7';
            app.a7.Text = 'a7';
            app.a7.Value = true;
            app.a0 = uicheckbox(app.AnalogInput);
            app.a0.Tag = 'a0';
            app.a0.Enable = 'off';
            app.a0.Text = 'a0';
            app.a0.Value = true;
            app.TriggerPanel = uipanel(app.figure1);
            app.TriggerPanel.Title = 'Trigger setings';
            app.TriggerPanel.Tag = 'TriggerPanel';
            app.stopTrigValue = uieditfield(app.TriggerPanel, 'text');
            app.stopTrigValue.ValueChangedFcn = createCallbackFcn(app, @stopTrigValueValueChanged, true);
            app.stopTrigValue.Tag = 'stopTrig';
            app.stopTrigValue.Value = '3.5';
            app.startTrigger = uilabel(app.TriggerPanel);
            app.startTrigger.Tag = 'triggerValue';
            app.startTrigger.Text = 'Start when trig >';
            app.startTrigValue = uieditfield(app.TriggerPanel, 'text');
            app.startTrigValue.ValueChangedFcn = createCallbackFcn(app, @startTrigValueValueChanged, true);
            app.startTrigValue.Tag = 'triggVal';
            app.startTrigValue.Value = '3';
            app.triggerUnit_1 = uilabel(app.TriggerPanel);
            app.triggerUnit_1.Tag = 'text13';
            app.triggerUnit_1.Text = '[V]';
            app.triggerUnit_2 = uilabel(app.TriggerPanel);
            app.triggerUnit_2.Tag = 'text14';
            app.triggerUnit_2.Text = '[V]';
            app.trigOrNot = uidropdown(app.TriggerPanel);
            app.trigOrNot.Items = {'With trigger', 'No trigger'};
            app.trigOrNot.ValueChangedFcn = createCallbackFcn(app, @trigOrNot_Callback, true);
            app.trigOrNot.Tag = 'trigOrNot';
            app.trigOrNot.Value = 'With trigger';
            app.stopTrigger = uibuttongroup(app.TriggerPanel);
            app.stopTrigger.Title = 'Stop when:';
            app.MaxtrigButton = uiradiobutton(app.stopTrigger);
            app.MaxtrigButton.Text = 'Max (trig)  <';
            app.MaxtrigButton.Value = true;
            app.MintrigButton = uiradiobutton(app.stopTrigger);
            app.MintrigButton.Text = 'Min  (trig)  <';
            app.listbox = uilistbox(app.figure1);
            app.listbox.Items = {'Listbox'};
            app.listbox.ValueChangedFcn = createCallbackFcn(app, @listbox_Callback, true);
            app.listbox.Tag = 'listbox';
            app.listbox.Value = 'Listbox';
            app.openFile = uibutton(app.figure1, 'push');
            app.openFile.ButtonPushedFcn = createCallbackFcn(app, @openFile_Callback, true);
            app.openFile.Tag = 'openFile';
            app.openFile.Text = 'Open file';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SampSampGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)sampsamp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end